classdef farkleFrontend < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        rollingGif          matlab.ui.control.Image
        StartButton         matlab.ui.control.Button
        Image9              matlab.ui.control.Image
        Label               matlab.ui.control.Label
        CurrentPlayerLabel  matlab.ui.control.Label
        PreviewScoreLabel   matlab.ui.control.Label
        ScoreThisTurnLabel  matlab.ui.control.Label
        Player2ScoreLabel   matlab.ui.control.Label
        Player1ScoreLabel   matlab.ui.control.Label
        Panel6              matlab.ui.container.Panel
        Image6              matlab.ui.control.Image
        Panel5              matlab.ui.container.Panel
        Image5              matlab.ui.control.Image
        Panel4              matlab.ui.container.Panel
        Image4              matlab.ui.control.Image
        Panel3              matlab.ui.container.Panel
        Image3              matlab.ui.control.Image
        Panel2              matlab.ui.container.Panel
        Image2              matlab.ui.control.Image
        Panel               matlab.ui.container.Panel
        Image               matlab.ui.control.Image
        Image7              matlab.ui.control.Image
        Image8              matlab.ui.control.Image
    end


    properties (Access = private)
        backend % farkleBackend.m
        sensorTimer
        diceImages
        diceSelectedImages
        diceImageHandles
        dicePanelHandles
        initialRollTriggered = false
        incrementHandTriggered = false
        selectDieTriggered = false
        scoringDebounce = false
        roundScoreTriggered = false
        scoreTurnDebounce = false
        rerollPossible = false
        rerollDebounce = false
        scoringPossible = false
    end
    
    methods (Access = private)
        
        function updateSensors(app)
            app.backend.updateOrientation();
            handIndex = app.backend.currentHandIndex;

            pitch = app.backend.TargetSignalP;
            roll = app.backend.TargetSignalR;
            
            %IF PLAYER 2 TURN
            if app.backend.currentPlayer ~= app.backend.tsPlayerID
                app.backend.readGameState();
                app.Player1ScoreLabel.Text = "Player 1 Score: " + app.backend.playerScores(1);
                app.Player2ScoreLabel.Text = "Player 2 Score: " + app.backend.playerScores(2);
                app.CurrentPlayerLabel.Text = "Current Player: " + app.backend.currentPlayer;
                app.Label.Text = "Waiting for Player " + app.backend.currentPlayer + "'s turn to end...";
                app.updateDice();
                return;
            end

            % INITIAl ROLL
            if roll < -50 && ~app.initialRollTriggered
                app.initialRollTriggered = true;
                app.scoringPossible = false;
                app.rollingGif.Visible = 'on';
                app.Label.Text = "Rolling Initial Set";
                pause(1);
                app.backend.initialRoll();
                updateDice(app);
                if app.backend.checkForBust()
                    app.backend.turnPoints = 0;
                    app.backend.scoreTurn();
                    app.backend.switchPlayer();
                    app.CurrentPlayerLabel.Text = "Current Player: " + app.backend.currentPlayer;
                    app.ScoreThisTurnLabel.Text = "Score This Turn: " + app.backend.turnPoints;
                    app.Player1ScoreLabel.Text = "Player 1 Score: " + app.backend.playerScores(1);
                    app.Player2ScoreLabel.Text = "Player 2 Score: " + app.backend.playerScores(2);
                    app.updateDice();
                    app.initialRollTriggered = false;
                    app.Label.Text = "BUST! Passing Turn to Player " + app.backend.currentPlayer;
                end
            end

            % REROLL
            if roll < -50 && app.rerollPossible && ~app.rerollDebounce
                app.rollingGif.Visible = 'on';
                app.Label.Text = "Rerolling Dice";
                pause(1);
                app.backend.rollRemainingDice();
                app.updateDice();
                app.rerollPossible = false;
                app.scoringPossible = false;
                app.rerollDebounce = true;
                app.roundScoreTriggered = false;
                app.ScoreThisTurnLabel.Text = "Score This Turn: " + app.backend.turnPoints;
                if app.backend.checkForBust()
                    app.backend.turnPoints = 0;
                    app.backend.scoreTurn();
                    app.backend.switchPlayer();
                    app.CurrentPlayerLabel.Text = "Current Player: " + app.backend.currentPlayer;
                    app.ScoreThisTurnLabel.Text = "Score This Turn: " + app.backend.turnPoints;
                    app.Player1ScoreLabel.Text = "Player 1 Score: " + app.backend.playerScores(1);
                    app.Player2ScoreLabel.Text = "Player 2 Score: " + app.backend.playerScores(2);
                    app.updateDice();
                    app.initialRollTriggered = false;
                    app.Label.Text = "BUST! Passing Turn to Player " + app.backend.currentPlayer;
                end
            end

            % SCORE AND END TURN
            if roll > 50 && app.initialRollTriggered && app.roundScoreTriggered && ~app.scoreTurnDebounce
                app.scoreTurnDebounce = true;
                app.backend.scoreTurn();
                app.backend.switchPlayer();
                app.backend.writeGameState();
                app.updateDice();
                app.CurrentPlayerLabel.Text = "Current Player: " + app.backend.currentPlayer;
                app.ScoreThisTurnLabel.Text = "Score This Turn: " + app.backend.turnPoints;
                app.Player1ScoreLabel.Text = "Player 1 Score: " + app.backend.playerScores(1);
                app.Player2ScoreLabel.Text = "Player 2 Score: " + app.backend.playerScores(2);
                app.roundScoreTriggered = false;
                app.initialRollTriggered = false;
                app.Label.Text = "Scoring and Passing Turn";
                if app.backend.playerScores(1) >= app.backend.gamePoints
                    app.Label.Text = "Game Over! Player 1 wins!";
                    return;
                elseif app.backend.playerScores(2) >= app.backend.gamePoints
                    app.Label.Text = "Game Over! Player 2 wins!";
                    return;
                end
            end

            % INCREMENT HAND
            if roll > 50 && ~app.incrementHandTriggered && ~app.roundScoreTriggered && app.initialRollTriggered
                app.incrementHandTriggered = true;
                app.backend.incrementHandIndex();
                app.updateDice();
                app.Label.Text = "Hand Incremented";
            end

            % SELECT DICE
            if pitch > 25 && ~app.selectDieTriggered && ~app.roundScoreTriggered && app.initialRollTriggered && ~isempty(app.backend.currentHand)
                app.selectDieTriggered = true;
                app.backend.selectDie(handIndex);
                app.updateDice();
                app.scoringPossible = true;
                app.Label.Text = "Dice Selected";
            end

            % SCORE HAND
            if pitch < -50 && ~app.scoringDebounce && ~app.roundScoreTriggered && app.scoringPossible
                app.scoringDebounce = true;
                app.roundScoreTriggered = true;
                app.backend.scoreHand();
                app.ScoreThisTurnLabel.Text = "Score This Turn: " + app.backend.turnPoints;
                app.rerollPossible = true;
                app.Label.Text = "Scoring Hand";
                if isempty(app.backend.currentHand)
                    app.rerollPossible = false;
                    app.initialRollTriggered = false;
                end
            end

            % DEBOUNCE FUNCTIONS
            if roll > -30
                app.rerollDebounce = false;
            end

            if roll < 30
                app.incrementHandTriggered = false;
                app.scoreTurnDebounce = false;
            end

            if pitch < 5
                app.selectDieTriggered = false;
            end

            if pitch > -30
                app.scoringDebounce = false;
            end
        end

        function updateDice(app)
            app.rollingGif.Visible = 'off';
            currentHand = app.backend.currentHand;
            handIndex = app.backend.currentHandIndex;
            selectedHand = app.backend.selectedHand;

            numCurrent = length(currentHand);
            numSelected = length(selectedHand);

            for i = 1:6
                app.diceImageHandles(i).Visible = "off";
                app.dicePanelHandles(i).BackgroundColor = [0.9 0.9 0.9];  % normal color
            end

            for i = 1:numCurrent
                dieValue = currentHand(i);
                imgPath = app.diceImages{dieValue};
                app.diceImageHandles(i).ImageSource = imgPath;
                app.diceImageHandles(i).Visible = "on";
            end

            for i = 1:numSelected
                slot = numCurrent + i;
                value = selectedHand(i);
                app.diceImageHandles(slot).ImageSource = app.diceImages{value};
                app.diceImageHandles(slot).Visible = "on";

                app.dicePanelHandles(slot).BackgroundColor = [0.3 1.0 0.3]; % green highlight
            end

            if handIndex >= 1 && handIndex <= length(currentHand)
                indexValue = currentHand(handIndex);
                app.diceImageHandles(handIndex).ImageSource = app.diceSelectedImages{indexValue};
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.backend = farkleBackend();

            app.diceImages = {"dice1.png", "dice2.png", "dice3.png", "dice4.png", "dice5.png", "dice6.png"};
            app.diceSelectedImages = {"dice1Selected.png", "dice2Selected.png", "dice3Selected.png", "dice4Selected.png", "dice5Selected.png", "dice6Selected.png"};
            app.diceImageHandles = [app.Image, app.Image2, app.Image3, app.Image4, app.Image5, app.Image6];
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.backend.connectDevice();
            pause(0.5);
            app.backend.beginLogging();
            app.sensorTimer = timer('ExecutionMode', 'fixedSpacing', 'Period', 0.05, 'TimerFcn', @(~,~) app.updateSensors());

            app.Player1ScoreLabel.Text = "Player 1 Score: " + app.backend.playerScores(1);
            app.Player2ScoreLabel.Text = "Player 2 Score: " + app.backend.playerScores(2);
            app.dicePanelHandles = [app.Panel, app.Panel2, app.Panel3, app.Panel4, app.Panel5, app.Panel6];
            
            pause(0.5);
            start(app.sensorTimer);
            app.Label.Text = "Device Connected! Detecting Orientation Values...";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image8 = uiimage(app.UIFigure);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'Wood Background.jpg');
            app.Image7 = uiimage(app.UIFigure);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'Metal Border.png');
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'dice1.PNG');
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.Image2 = uiimage(app.Panel2);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'dice2.PNG');
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'Panel3';
            app.Image3 = uiimage(app.Panel3);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'dice3.PNG');
            app.Panel4 = uipanel(app.UIFigure);
            app.Panel4.Title = 'Panel4';
            app.Image4 = uiimage(app.Panel4);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'dice4.PNG');
            app.Panel5 = uipanel(app.UIFigure);
            app.Panel5.Title = 'Panel5';
            app.Image5 = uiimage(app.Panel5);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'dice5.PNG');
            app.Panel6 = uipanel(app.UIFigure);
            app.Panel6.Title = 'Panel6';
            app.Image6 = uiimage(app.Panel6);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'dice6.PNG');
            app.Player1ScoreLabel = uilabel(app.UIFigure);
            app.Player1ScoreLabel.Text = 'Player 1 Score: ';
            app.Player2ScoreLabel = uilabel(app.UIFigure);
            app.Player2ScoreLabel.Text = 'Player 2 Score: ';
            app.ScoreThisTurnLabel = uilabel(app.UIFigure);
            app.ScoreThisTurnLabel.Text = 'Score This Turn: ';
            app.PreviewScoreLabel = uilabel(app.UIFigure);
            app.PreviewScoreLabel.Text = 'Preview Score: ';
            app.CurrentPlayerLabel = uilabel(app.UIFigure);
            app.CurrentPlayerLabel.Text = 'Current Player: ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Image9 = uiimage(app.UIFigure);
            app.Image9.ImageSource = fullfile(pathToMLAPP, 'Farkle Logo1.png');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.rollingGif = uiimage(app.UIFigure);
            app.rollingGif.Visible = 'off';
            app.rollingGif.ImageSource = fullfile(pathToMLAPP, 'BlankDiceRoll.gif');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = farkleFrontend

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end