classdef farkleFrontend_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        PreviewScoreLabel   matlab.ui.control.Label
        ScoreThisTurnLabel  matlab.ui.control.Label
        Player2ScoreLabel   matlab.ui.control.Label
        Player1ScoreLabel   matlab.ui.control.Label
        Panel6              matlab.ui.container.Panel
        Image6              matlab.ui.control.Image
        Panel5              matlab.ui.container.Panel
        Image5              matlab.ui.control.Image
        Panel4              matlab.ui.container.Panel
        Image4              matlab.ui.control.Image
        Panel3              matlab.ui.container.Panel
        Image3              matlab.ui.control.Image
        Panel2              matlab.ui.container.Panel
        Image2              matlab.ui.control.Image
        Panel               matlab.ui.container.Panel
        dice1               matlab.ui.control.Image
        StartButton         matlab.ui.control.Button
        metal               matlab.ui.control.Image
        wood                matlab.ui.control.Image
    end

    
    properties (Access = private)
        backend % farkleBackend.m
        sensorTimer
        diceImages % NOTE: Aspen if you are adding in the dice images please name them to the corresponding names in app.diceImages on line 48 -Zak
        diceImageHandles
        initialRollTriggered = false
        incrementHandTriggered = false
        selectDieTriggered = false
        dicePanelHandles
        scoringTriggered = false
    end
    
    methods (Access = private)
        
        function updateSensors(app)
            app.backend.updateOrientation();
            handIndex = app.backend.currentHandIndex;

            pitch = app.backend.TargetSignalP;
            roll = app.backend.TargetSignalR;

            if roll < -50 && ~app.initialRollTriggered
                app.initialRollTriggered = true;
                app.backend.initialRoll();
                updateDice(app);
            end

            if roll > 50 && ~app.incrementHandTriggered
                app.incrementHandTriggered = true;
                app.backend.incrementHandIndex();
                updateDice(app);
            end

            if roll < 30
                app.incrementHandTriggered = false;
            end

            if pitch > 25 && ~app.selectDieTriggered
                app.selectDieTriggered = true;
                app.backend.selectDie(handIndex);
                app.updateDice();
            end

            if pitch < 5
                app.selectDieTriggered = false;
            end

            if pitch < -50 && ~app.scoringTriggered
                app.scoringTriggered = true;
                app.backend.scoreHand();
                app.ScoreThisTurnLabel.Text = "Score This Turn: " + app.backend.turnPoints;
            end

            if pitch > -30
                app.scoringTriggered = false;
            end

        end

        function updateDice(app)
            currentHand = app.backend.currentHand;
            handIndex = app.backend.currentHandIndex;
            selectedHand = app.backend.selectedHand;

            numCurrent = length(currentHand);
            numSelected = length(selectedHand);

            for i = 1:6
                app.diceImageHandles(i).Visible = "on";
                app.dicePanelHandles(i).BackgroundColor = [0.9 0.9 0.9];  % normal color
            end

            for i = 1:numCurrent
                dieValue = currentHand(i);
                imgPath = app.diceImages{dieValue};
                app.diceImageHandles(i).ImageSource = imgPath;
            end

            for i = 1:numSelected
                slot = numCurrent + i;
                value = selectedHand(i);
                app.diceImageHandles(slot).ImageSource = app.diceImages{value};

                app.dicePanelHandles(slot).BackgroundColor = [0.3 1.0 0.3];
            end

            if handIndex >= 1 && handIndex <= length(currentHand)
                app.dicePanelHandles(handIndex).BackgroundColor = [1 .84 0]; % gold highlight
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.backend = farkleBackend();

            app.diceImages = {"dice1.png", "dice2.png", "dice3.png", "dice4.png", "dice5.png", "dice6.png"};
            app.diceImageHandles = [app.dice1, app.Image2, app.Image3, app.Image4, app.Image5, app.Image6];
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            app.backend.connectDevice();
            pause(0.5);
            app.backend.beginLogging();
            app.sensorTimer = timer('ExecutionMode', 'fixedSpacing', 'Period', 0.05, 'TimerFcn', @(~,~) app.updateSensors());


            app.Player1ScoreLabel.Text = "Player 1 Score: " + app.backend.playerScores(1);
            app.Player2ScoreLabel.Text = "Player 2 Score: " + app.backend.playerScores(2);

            pause(0.5);
            start(app.sensorTimer);

            app.dicePanelHandles = [app.Panel, app.Panel2, app.Panel3, app.Panel4, app.Panel5, app.Panel6];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.wood = uiimage(app.UIFigure);
            app.wood.ImageSource = fullfile(pathToMLAPP, 'Wood Background.jpg');
            app.metal = uiimage(app.UIFigure);
            app.metal.ImageSource = fullfile(pathToMLAPP, 'Metal Border.png');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.dice1 = uiimage(app.Panel);
            app.dice1.ImageSource = fullfile(pathToMLAPP, 'dice1.png');
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.Image2 = uiimage(app.Panel2);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'dice2.png');
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'Panel3';
            app.Image3 = uiimage(app.Panel3);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'dice3.png');
            app.Panel4 = uipanel(app.UIFigure);
            app.Panel4.Title = 'Panel4';
            app.Image4 = uiimage(app.Panel4);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'dice4.png');
            app.Panel5 = uipanel(app.UIFigure);
            app.Panel5.Title = 'Panel5';
            app.Image5 = uiimage(app.Panel5);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'dice5.png');
            app.Panel6 = uipanel(app.UIFigure);
            app.Panel6.Title = 'Panel6';
            app.Image6 = uiimage(app.Panel6);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'dice6.png');
            app.Player1ScoreLabel = uilabel(app.UIFigure);
            app.Player1ScoreLabel.Text = 'Player 1 Score: ';
            app.Player2ScoreLabel = uilabel(app.UIFigure);
            app.Player2ScoreLabel.Text = 'Player 2 Score: ';
            app.ScoreThisTurnLabel = uilabel(app.UIFigure);
            app.ScoreThisTurnLabel.Text = 'Score This Turn: ';
            app.PreviewScoreLabel = uilabel(app.UIFigure);
            app.PreviewScoreLabel.Text = 'Preview Score: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = farkleFrontend_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end